classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        V18Label                        matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        FunctionGeneratorTab            matlab.ui.container.Tab
        SimulationtimeEditFieldLabel    matlab.ui.control.Label
        Phase6EditField                 matlab.ui.control.NumericEditField
        PhaseEditFieldLabel_5           matlab.ui.control.Label
        PhaseEditFieldLabel_4           matlab.ui.control.Label
        PhaseEditFieldLabel_3           matlab.ui.control.Label
        PhaseEditFieldLabel_2           matlab.ui.control.Label
        amplitude6EditField             matlab.ui.control.NumericEditField
        Phase5EditField                 matlab.ui.control.NumericEditField
        amplitudeEditFieldLabel_5       matlab.ui.control.Label
        amplitude5EditField             matlab.ui.control.NumericEditField
        Phase4EditField                 matlab.ui.control.NumericEditField
        amplitudeEditFieldLabel_4       matlab.ui.control.Label
        amplitude4EditField             matlab.ui.control.NumericEditField
        Phase3EditField                 matlab.ui.control.NumericEditField
        amplitudeEditFieldLabel_3       matlab.ui.control.Label
        amplitude3EditField             matlab.ui.control.NumericEditField
        amplitudeEditFieldLabel_2       matlab.ui.control.Label
        Phase2EditField                 matlab.ui.control.NumericEditField
        PhaseEditFieldLabel             matlab.ui.control.Label
        amplitude2EditField             matlab.ui.control.NumericEditField
        amplitudeEditFieldLabel         matlab.ui.control.Label
        thLabel_3                       matlab.ui.control.Label
        thLabel_2                       matlab.ui.control.Label
        thLabel                         matlab.ui.control.Label
        rdLabel                         matlab.ui.control.Label
        ndLabel                         matlab.ui.control.Label
        harmonicsLabel                  matlab.ui.control.Label
        SimulationtimeEditField         matlab.ui.control.NumericEditField
        fallingrisingtimeofDCEditField  matlab.ui.control.NumericEditField
        fallingrisingtimeofDCEditFieldLabel  matlab.ui.control.Label
        DcTypeDropDown                  matlab.ui.control.DropDown
        DccomponentEditField            matlab.ui.control.NumericEditField
        DccomponentEditFieldLabel       matlab.ui.control.Label
        SamplingfrequenctyEditField     matlab.ui.control.NumericEditField
        SamplingfrequenctyEditFieldLabel  matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        PhaseshiftEditField             matlab.ui.control.NumericEditField
        PhaseshiftEditFieldLabel        matlab.ui.control.Label
        AcmaxamplitudeEditField         matlab.ui.control.NumericEditField
        AcmaxamplitudeEditFieldLabel    matlab.ui.control.Label
        DcTypeDropDownLabel             matlab.ui.control.Label
        GenerateattheendButton          matlab.ui.control.Button
        GenerateButton                  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        TwoSampleTab                    matlab.ui.container.Tab
        Modelfrequencyf0EditField       matlab.ui.control.NumericEditField
        Modelfrequencyf0EditFieldLabel  matlab.ui.control.Label
        startButton_3                   matlab.ui.control.Button
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        ThreeSampleTab                  matlab.ui.container.Tab
        startButton_2                   matlab.ui.control.Button
        ModelfrequencyEditField         matlab.ui.control.NumericEditField
        ModelfrequencyEditFieldLabel    matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        PeakbasedTab                    matlab.ui.container.Tab
        ModelFrequencyf0EditField       matlab.ui.control.NumericEditField
        ModelFrequencyf0EditFieldLabel  matlab.ui.control.Label
        startButton                     matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        dftTab                          matlab.ui.container.Tab
        startButton_4                   matlab.ui.control.Button
        ModelFrequencyf0EditField_2     matlab.ui.control.NumericEditField
        ModelFrequencyf0EditField_2Label  matlab.ui.control.Label
        UIAxes7                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        
        function [Y,angle] = TwoSample(~,k,freq0,Tsample)
            %TwoSample This function applies the three sample predictive alghorithm
            %to any given input data k, freq0 is the model frequency, while Tsample is
            %the sampling time
            Ys=zeros(1,length(k));
	        Yc=k;
	        Ys(1)=(Yc(1)*cos(2*freq0*pi*Tsample)-0)/sin(2*freq0*pi*Tsample);
            Y=zeros(1,length(k));
            angle=zeros(1,length(k));
            for j=2:length(k)
                Ys(j)=(Yc(j)*cos(2*freq0*pi*Tsample)-Yc(j-1))/sin(2*freq0*pi*Tsample);
            end
	        for z=1:length(k)
                Y(z)=sqrt(Ys(z)^2+Yc(z)^2);
                angle(z)=atand(Ys(z)/Yc(z));
	        end
        end

        
        function [Y3,angle3] = ThreeSample(~,k,freq0,Tsample)
            %ThreeSample This function applies the three sample predictive alghorithm
            %to any given input data k, freq0 is the model frequency, while Tsample is
            %the sampling time
            Ys3 = zeros(1,length(k));
            Yc3 = zeros(1,length(k));
            Yc3(1)=(0*cos(2*freq0*pi*Tsample)+0+k(1)*cos(2*freq0*pi*Tsample))/((2*(cos(2*freq0*pi*Tsample))^2)+1);
            Ys3(1)=(k(1)-0)/(2*sin(2*freq0*pi*Tsample));
            Yc3(2)=(0*cos(2*freq0*pi*Tsample)+k(1)+k(2)*cos(2*freq0*pi*Tsample))/((2*(cos(2*freq0*pi*Tsample))^2)+1);
            Ys3(2)=(k(2)-0)/(2*sin(2*freq0*pi*Tsample));
            Y3=zeros(1,length(k));
            angle3=zeros(1,length(k));
            for j=3:length(k)
                Yc3(j)=(k(j-2)*cos(2*freq0*pi*Tsample)+k(j-1)+k(j)*cos(2*freq0*pi*Tsample))/((2*(cos(2*freq0*pi*Tsample))^2)+1);
            end
            for j=3:length(k)
                Ys3(j)=(k(j)-k(j-2))/(2*sin(2*freq0*pi*Tsample));
            end
            for z=1:length(k)
                Y3(z)=sqrt(Ys3(z)^2+Yc3(z)^2);
                angle3(z)=atand(Ys3(z)/Yc3(z));
            end
        end
        
        function [Yp] = PeakBased(~,k,freq0,Tsample)
            %PeakBased This function applies the three sample predictive alghorithm
            %to any given input data k, freq0 is the model frequency, while Tsample is
            %the sampling time
            Yp=zeros(1,length(k));
            Yp(1)=(1/(2*pi*freq0))*sqrt((k(1)/Tsample)^2+(2*pi*freq0*k(1))^2);
            for j=2:length(k)
                Yp(j)=(1/(2*pi*freq0))*sqrt(((k(j)-k(j-1))/Tsample)^2+(2*pi*freq0*k(j))^2);
            end
        end
        function [YDFT,ThetaDFT,YcDFT,YsDFT] = dft(~,k,freq0,fsample)
            %dft This function applies the dft alghorithm
            %to any given input data k, freq0 is the model frequency, while fsample is
            %the sampling frequency
            Nw=fsample/freq0;           %setting the window size
            YcDFT=zeros((Nw/2+1),length(k));   %setting the variable size for optmization
            YsDFT=zeros((Nw/2+1),length(k));   %setting the variable size for optmization
            YDFT=zeros((Nw/2+1),length(k));    %setting the variable size for optmization
            ThetaDFT=zeros((Nw/2+1),length(k));%setting the variable size for optmization
            for j= (-Nw+2):length(k)-Nw+1             %j is the window number 
                if j<1                              %if j is less than 1 put zeros to fill k1
                    k1 =[zeros(1,abs(j)+1), k(1:(Nw+j-1))]; %k1 is the window data
                else
                k1 = k(j:(Nw+j-1));             %if not put k1 as the data location from point j to the end of the window
                end
                for jj=0:((Nw/2))                   %jj is the number of the harmonic 0 is DC while 1 is Fundmental and so on
                    if jj==0                         %as zero component is only the mean      
                        YcDFT(jj+1,j+Nw-1)=mean(k1.*(cos((jj*1/Nw*2*pi)*(0:(Nw-1))))); %here we just plugin the numbers note: tthe mean is the addition over the number of elements
                        YsDFT(jj+1,j+Nw-1)=mean(k1.*(sin((jj*1/Nw*2*pi)*(0:(Nw-1))))); %here we just plugin the numbers note: tthe mean is the addition over the number of elements
                        YDFT(jj+1,j+Nw-1)=sqrt(YcDFT(jj+1,j+Nw-1)^2+YsDFT(jj+1,j+Nw-1)^2); %here I calculate the magnitude
                    else
                        YcDFT(jj+1,j+Nw-1)=2*mean(k1.*(cos((jj*1/Nw*2*pi)*(0:(Nw-1))))); %here we just plugin the numbers note: tthe mean is the addition over the number of elements
                        YsDFT(jj+1,j+Nw-1)=2*mean(k1.*(sin((jj*1/Nw*2*pi)*(0:(Nw-1))))); %here we just plugin the numbers note: tthe mean is the addition over the number of elements
                        YDFT(jj+1,j+Nw-1)=sqrt(YcDFT(jj+1,j+Nw-1)^2+YsDFT(jj+1,j+Nw-1)^2); %here I calculate the magnitude 
                    end
                    if YcDFT(jj+1,j+Nw-1)==0                                         %here I am just avoiding undefined numbers
                        if YsDFT(jj+1,j+Nw-1)==0                                     % if both sine and cos somehow equal zero which can only happen at the first window I am avoiding a null value by putting zero this window is not good so no proplem
                        ThetaDFT(jj+1,j+Nw-1)=0;        
                        else                                                         %as tan 90 is undefined
                        ThetaDFT(jj+1,j+Nw-1)=90;
                        end
                    else
                    ThetaDFT(jj+1,j+Nw-1)=atand(YsDFT(jj+1,j+Nw-1)/YcDFT(jj+1,j+Nw-1));                            %if there is no proplem then calculate normally
                    end
                end
            end
    end


    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            %data input and declaration
            global k to flag Tsample 
            a=app.AcmaxamplitudeEditField.Value;
            phase=app.PhaseshiftEditField.Value;
            freq=app.FrequencyEditField.Value;
            fsample=app.SamplingfrequenctyEditField.Value;
            Tsample=1/fsample;
            DCcomp=app.DccomponentEditField.Value;
            Tr=app.fallingrisingtimeofDCEditField.Value+10^(-1000);
            Tsim1=app.SimulationtimeEditField.Value;
            to=Tsim1-Tsample;
            a2=app.amplitude2EditField.Value;
            a3=app.amplitude3EditField.Value;
            a4=app.amplitude4EditField.Value;
            a5=app.amplitude5EditField.Value;
            a6=app.amplitude6EditField.Value;
            phase2=app.Phase2EditField.Value;
            phase3=app.Phase3EditField.Value;
            phase4=app.Phase4EditField.Value;
            phase5=app.Phase5EditField.Value;
            phase6=app.Phase6EditField.Value;
            %making the sample of each wave component seperatly
            AC1=a* sin((0:Tsample:(Tsim1-Tsample))*2*pi*freq+phase*pi/180);
            AC2=a2*sin((0:Tsample:(Tsim1-Tsample))*2*pi*freq*2+phase2*pi/180);
            AC3=a3*sin((0:Tsample:(Tsim1-Tsample))*2*pi*freq*3+phase3*pi/180);
            AC4=a4*sin((0:Tsample:(Tsim1-Tsample))*2*pi*freq*4+phase4*pi/180);
            AC5=a5*sin((0:Tsample:(Tsim1-Tsample))*2*pi*freq*5+phase5*pi/180);
            AC6=a6*sin((0:Tsample:(Tsim1-Tsample))*2*pi*freq*6+phase6*pi/180);

            if DCcomp ~= 0
                if Tr ~= 0
                    switch app.DcTypeDropDown.Value
                        case 'Constant'
                            %creating the sampled wave in its entirity
                            %while adding the dc component
                            k=AC1+AC2+AC3+AC4+AC5+AC6+DCcomp;
                        case 'Rising'
                            %creating the sampled wave in its entirity
                            %while adding the dc component
                            k=AC1+AC2+AC3+AC4+AC5+AC6;
                            for i=1:length(k)
                                DCcomp1=(DCcomp)-(DCcomp)*exp(-(Tsample/Tr*(i-1))*5);
                                k(i)=k(i)+DCcomp1;
                            end
                        case 'Decaying'
                            %creating the sampled wave in its entirity
                            %while adding the dc component
                            k=AC1+AC2+AC3+AC4+AC5+AC6;
                            for i=1:length(k)
                                DCcomp1=(DCcomp)*exp(-(Tsample/Tr*(i-1))*5);
                                k(i)=k(i)+DCcomp1;
                            end
                    end
                else
                    %creating the sampled wave in its entirity
                    %while adding the dc component
                    k=AC1+AC2+AC3+AC4+AC5+AC6+DCcomp;
                end
            else
                %creating the sampled wave in its entirity
                %while adding the dc component
                k=AC1+AC2+AC3+AC4+AC5+AC6;
            end
            %plotting the values
            plot(app.UIAxes,0:Tsample:to,k)
            flag=1;    % this flag enables and disables the henerate at the end button
        end

        % Button pushed function: GenerateattheendButton
        function GenerateattheendButtonPushed(app, event)
            %data input and declaration
            global k flag Tsample to
            if flag==1
            a=app.AcmaxamplitudeEditField.Value;
            phase=app.PhaseshiftEditField.Value;
            freq=app.FrequencyEditField.Value;
            DCcomp=app.DccomponentEditField.Value;
            Tr=app.fallingrisingtimeofDCEditField.Value;
            Tsim1=app.SimulationtimeEditField.Value;
            a2=app.amplitude2EditField.Value;
            a3=app.amplitude3EditField.Value;
            a4=app.amplitude4EditField.Value;
            a5=app.amplitude5EditField.Value;
            a6=app.amplitude6EditField.Value;
            phase2=app.Phase2EditField.Value;
            phase3=app.Phase3EditField.Value;
            phase4=app.Phase4EditField.Value;
            phase5=app.Phase5EditField.Value;
            phase6=app.Phase6EditField.Value;
            %making the sample of each wave component seperatly
            AC1=a*sin((0:Tsample:(Tsim1-Tsample))*2*pi*freq+phase*pi/180);
            AC2=a2*sin((0:Tsample:(Tsim1-Tsample))*2*pi*freq*2+phase2*pi/180);
            AC3=a3*sin((0:Tsample:(Tsim1-Tsample))*2*pi*freq*3+phase3*pi/180);
            AC4=a4*sin((0:Tsample:(Tsim1-Tsample))*2*pi*freq*4+phase4*pi/180);
            AC5=a5*sin((0:Tsample:(Tsim1-Tsample))*2*pi*freq*5+phase5*pi/180);
            AC6=a6*sin((0:Tsample:(Tsim1-Tsample))*2*pi*freq*6+phase6*pi/180);
            if DCcomp ~= 0
                if Tr ~= 0 
                    switch app.DcTypeDropDown.Value
                        case 'Constant'
                            %creating the sampled wave in its entirity
                            %while adding the dc component
                            k2=AC1+AC2+AC3+AC4+AC5+AC6+DCcomp;
                        case 'Rising'
                            %creating the sampled wave in its entirity
                            %while adding the dc component
                            k2=AC1+AC2+AC3+AC4+AC5+AC6;
                            for i=1:length(k2)
                                DCcomp1=(DCcomp)-(DCcomp)*exp(-(Tsample/Tr*(i-1))*5);
                                k2(i)=k2(i)+DCcomp1;
                            end
                        case 'Decaying'
                            %creating the sampled wave in its entirity
                            %while adding the dc component
                            k2=AC1+AC2+AC3+AC4+AC5+AC6;
                            for i=1:length(k2)
                                DCcomp1=(DCcomp)*exp(-(Tsample/Tr*(i-1))*5);
                                k2(i)=k2(i)+DCcomp1;
                            end
                    end
                else
                    %creating the sampled wave in its entirity
                    %while adding the dc component
                    k2=AC1+AC2+AC3+AC4+AC5+AC6+DCcomp;
                end
            else
                %creating the sampled wave in its entirity
                %while adding the dc component
                k2=AC1+AC2+AC3+AC4+AC5+AC6;
            end
            k=[k,k2];   %adding the old k with new one 
            %plotting the values
            to=(Tsample*(length(k)-1));
            plot(app.UIAxes,0:Tsample:to,k)
            end
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            %here we simply plot and calculate the values the values
            global to k Tsample
            freq0=app.ModelFrequencyf0EditField.Value;
            [Yp] = PeakBased(app,k,freq0,Tsample);
            plot(app.UIAxes2,0:Tsample:to,Yp);
        end

        % Button pushed function: startButton_2
        function startButton_2Pushed(app, event)
            global to k Tsample
            freq0=app.ModelfrequencyEditField.Value;
            [Y3,angle3] = ThreeSample(app,k,freq0,Tsample);
            plot(app.UIAxes4,0:Tsample:to,Y3);
            plot(app.UIAxes3,0:Tsample:to,angle3);
        end

        % Button pushed function: startButton_3
        function startButton_3Pushed(app, event)
            global to k Tsample
            freq0=app.Modelfrequencyf0EditField.Value;
            [Y,angle] = TwoSample(app,k,freq0,Tsample);
            plot(app.UIAxes5,0:Tsample:to,Y);
            plot(app.UIAxes6,0:Tsample:to,angle);
        end

        % Button pushed function: startButton_4
        function startButton_4Pushed(app, event)
           global k Tsample %to
           freq0=app.ModelFrequencyf0EditField_2.Value;
           [YDFT] = dft(app,k,freq0,1/Tsample);
           %Nw=1/(freq0*Tsample);
           Yfinal=transpose(YDFT);
           Yfmean=mean(Yfinal);
           %thetafinal=transpose(ThetaDFT);
           %thetafmean=mean(thetafinal);
           plot(app.UIAxes7,(0:length(Yfmean)-1),Yfmean,'rs')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FunctionGeneratorTab = uitab(app.TabGroup);
            app.FunctionGeneratorTab.Title = 'Function Generator';
            app.UIAxes = uiaxes(app.FunctionGeneratorTab);
            title(app.UIAxes, 'Wave')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.GenerateButton = uibutton(app.FunctionGeneratorTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.GenerateattheendButton = uibutton(app.FunctionGeneratorTab, 'push');
            app.GenerateattheendButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateattheendButtonPushed, true);
            app.GenerateattheendButton.Text = 'Generate at the end';
            app.DcTypeDropDownLabel = uilabel(app.FunctionGeneratorTab);
            app.DcTypeDropDownLabel.Text = 'Dc Type';
            app.AcmaxamplitudeEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.AcmaxamplitudeEditFieldLabel.Text = 'Ac max amplitude';
            app.AcmaxamplitudeEditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.PhaseshiftEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.PhaseshiftEditFieldLabel.Text = 'Phase shift';
            app.PhaseshiftEditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.FrequencyEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.SamplingfrequenctyEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.SamplingfrequenctyEditFieldLabel.Text = 'Sampling frequencty';
            app.SamplingfrequenctyEditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.DccomponentEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.DccomponentEditFieldLabel.Text = 'Dc component';
            app.DccomponentEditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.DcTypeDropDown = uidropdown(app.FunctionGeneratorTab);
            app.DcTypeDropDown.Items = {'Rising', 'Decaying', 'Constant'};
            app.DcTypeDropDown.Value = 'Constant';
            app.fallingrisingtimeofDCEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.fallingrisingtimeofDCEditFieldLabel.Text = 'falling/rising time of DC';
            app.fallingrisingtimeofDCEditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.SimulationtimeEditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.harmonicsLabel = uilabel(app.FunctionGeneratorTab);
            app.harmonicsLabel.Text = 'harmonics';
            app.ndLabel = uilabel(app.FunctionGeneratorTab);
            app.ndLabel.Text = '2nd';
            app.rdLabel = uilabel(app.FunctionGeneratorTab);
            app.rdLabel.Text = '3rd';
            app.thLabel = uilabel(app.FunctionGeneratorTab);
            app.thLabel.Text = '4th';
            app.thLabel_2 = uilabel(app.FunctionGeneratorTab);
            app.thLabel_2.Text = '5th';
            app.thLabel_3 = uilabel(app.FunctionGeneratorTab);
            app.thLabel_3.Text = '6th';
            app.amplitudeEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.amplitudeEditFieldLabel.Text = 'amplitude';
            app.amplitude2EditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.PhaseEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.Phase2EditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.amplitudeEditFieldLabel_2 = uilabel(app.FunctionGeneratorTab);
            app.amplitudeEditFieldLabel_2.Text = 'amplitude';
            app.amplitude3EditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.amplitudeEditFieldLabel_3 = uilabel(app.FunctionGeneratorTab);
            app.amplitudeEditFieldLabel_3.Text = 'amplitude';
            app.Phase3EditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.amplitude4EditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.amplitudeEditFieldLabel_4 = uilabel(app.FunctionGeneratorTab);
            app.amplitudeEditFieldLabel_4.Text = 'amplitude';
            app.Phase4EditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.amplitude5EditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.amplitudeEditFieldLabel_5 = uilabel(app.FunctionGeneratorTab);
            app.amplitudeEditFieldLabel_5.Text = 'amplitude';
            app.Phase5EditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.amplitude6EditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.PhaseEditFieldLabel_2 = uilabel(app.FunctionGeneratorTab);
            app.PhaseEditFieldLabel_2.Text = 'Phase';
            app.PhaseEditFieldLabel_3 = uilabel(app.FunctionGeneratorTab);
            app.PhaseEditFieldLabel_3.Text = 'Phase';
            app.PhaseEditFieldLabel_4 = uilabel(app.FunctionGeneratorTab);
            app.PhaseEditFieldLabel_4.Text = 'Phase';
            app.PhaseEditFieldLabel_5 = uilabel(app.FunctionGeneratorTab);
            app.PhaseEditFieldLabel_5.Text = 'Phase';
            app.Phase6EditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.SimulationtimeEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.SimulationtimeEditFieldLabel.Text = 'Simulation time';
            app.TwoSampleTab = uitab(app.TabGroup);
            app.TwoSampleTab.Title = 'Two Sample';
            app.UIAxes5 = uiaxes(app.TwoSampleTab);
            title(app.UIAxes5, 'Magnitude vs time')
            xlabel(app.UIAxes5, 'time')
            ylabel(app.UIAxes5, 'Magnitude')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.TwoSampleTab);
            title(app.UIAxes6, 'Angle vs time')
            xlabel(app.UIAxes6, 'time')
            ylabel(app.UIAxes6, 'Magnitude')
            zlabel(app.UIAxes6, 'Z')
            app.startButton_3 = uibutton(app.TwoSampleTab, 'push');
            app.startButton_3.ButtonPushedFcn = createCallbackFcn(app, @startButton_3Pushed, true);
            app.startButton_3.Text = 'start';
            app.Modelfrequencyf0EditFieldLabel = uilabel(app.TwoSampleTab);
            app.Modelfrequencyf0EditFieldLabel.Text = 'Model frequency f0';
            app.Modelfrequencyf0EditField = uieditfield(app.TwoSampleTab, 'numeric');
            app.ThreeSampleTab = uitab(app.TabGroup);
            app.ThreeSampleTab.Title = 'Three Sample';
            app.UIAxes3 = uiaxes(app.ThreeSampleTab);
            title(app.UIAxes3, 'angle vs time')
            xlabel(app.UIAxes3, 'time')
            ylabel(app.UIAxes3, 'angle')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.ThreeSampleTab);
            title(app.UIAxes4, 'magnitude vs time')
            xlabel(app.UIAxes4, 'time')
            ylabel(app.UIAxes4, 'magnitude')
            zlabel(app.UIAxes4, 'Z')
            app.ModelfrequencyEditFieldLabel = uilabel(app.ThreeSampleTab);
            app.ModelfrequencyEditFieldLabel.Text = 'Model frequency';
            app.ModelfrequencyEditField = uieditfield(app.ThreeSampleTab, 'numeric');
            app.startButton_2 = uibutton(app.ThreeSampleTab, 'push');
            app.startButton_2.ButtonPushedFcn = createCallbackFcn(app, @startButton_2Pushed, true);
            app.startButton_2.Text = 'start';
            app.PeakbasedTab = uitab(app.TabGroup);
            app.PeakbasedTab.Title = 'Peak based';
            app.UIAxes2 = uiaxes(app.PeakbasedTab);
            title(app.UIAxes2, 'Magnitude vs time')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Magnitude')
            zlabel(app.UIAxes2, 'Z')
            app.startButton = uibutton(app.PeakbasedTab, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.ModelFrequencyf0EditFieldLabel = uilabel(app.PeakbasedTab);
            app.ModelFrequencyf0EditFieldLabel.Text = 'Model Frequency f0';
            app.ModelFrequencyf0EditField = uieditfield(app.PeakbasedTab, 'numeric');
            app.dftTab = uitab(app.TabGroup);
            app.dftTab.Title = 'dft';
            app.UIAxes7 = uiaxes(app.dftTab);
            title(app.UIAxes7, 'magnitude vs frequency')
            xlabel(app.UIAxes7, 'N harmonic')
            ylabel(app.UIAxes7, 'magnitude')
            zlabel(app.UIAxes7, 'Z')
            app.ModelFrequencyf0EditField_2Label = uilabel(app.dftTab);
            app.ModelFrequencyf0EditField_2Label.Text = 'Model Frequency f0';
            app.ModelFrequencyf0EditField_2 = uieditfield(app.dftTab, 'numeric');
            app.startButton_4 = uibutton(app.dftTab, 'push');
            app.startButton_4.ButtonPushedFcn = createCallbackFcn(app, @startButton_4Pushed, true);
            app.startButton_4.Text = 'start';
            app.V18Label = uilabel(app.UIFigure);
            app.V18Label.Text = 'V1.8';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end